classdef ulgGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        LoadFilesPanel   matlab.ui.container.Panel
        Load             matlab.ui.control.Button
        OptionsPanel     matlab.ui.container.Panel
        Exit             matlab.ui.control.Button
        StatsPanel       matlab.ui.container.Panel
        Max_u            matlab.ui.control.Label
        Max_v            matlab.ui.control.Label
        Max_alt          matlab.ui.control.Label
        Image2           matlab.ui.control.Image
        TabGroup         matlab.ui.container.TabGroup
        PositionTab      matlab.ui.container.Tab
        Alt              matlab.ui.control.Button
        XYZ              matlab.ui.control.Button
        VelocityTab      matlab.ui.container.Tab
        Vel              matlab.ui.control.Button
        AccelerationTab  matlab.ui.container.Tab
        Accel            matlab.ui.control.Button
        LowPassFilterCutOffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        LPFFreq          matlab.ui.control.EditField
        SetButton        matlab.ui.control.Button
        ReadyLampLabel   matlab.ui.control.Label
        ReadyLamp        matlab.ui.control.Lamp
        ElseTab          matlab.ui.container.Tab
    end

    
    properties (Access = private)
        ulg_file % Description
        MATname
        max_u
        max_v
        max_h
    end
    
    methods (Access = private)
        
        function fileName = corretName(~,ulg_file)
            % The log file name, no file specifier required
            fileName_check = ulg_file;
            dotLocations = find(fileName_check == '.');
            if isempty(dotLocations)
               	% No dots at all found so just take entire name.
               	fileName = ulg_file;
            else
               	% Take up to , but not including, the first dot.
               	fileName = ulg_file(1:dotLocations(1)-1);
            end
            
        end
        
        function matName = addMATextention(~,ulg_file)
            matName = sprintf('%s.mat',ulg_file);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            app.ulg_file = uigetfile('*.ulg');
            app.ulg_file = corretName(app,app.ulg_file);
            app.MATname = addMATextention(app,app.ulg_file);
            ulg2mat(app.ulg_file);
            [app.max_v,app.max_u,app.max_h] = getStats(app.MATname);
            str_v = sprintf('Maximum Velocity is %.3f [m/s]',app.max_v);
            str_u = sprintf('Maximum Acceleration is %.3f [m/s^2]',app.max_u);
            str_h = sprintf('Maximum Hight is %.3f [m]',app.max_h);
            app.Max_v.Text = str_v;
            app.Max_u.Text = str_u;
            app.Max_alt.Text = str_h;
        end

        % Button pushed function: XYZ
        function XYZButtonPushed(app, event)
            plotXYZ(app.MATname);
        end

        % Button pushed function: Alt
        function AltButtonPushed(app, event)
            plotAlt(app.MATname);
        end

        % Button pushed function: Accel
        function AccelButtonPushed(app, event)
            plotAccel(app.MATname,str2double(app.LPFFreq.Value));
        end

        % Button pushed function: Vel
        function VelButtonPushed(app, event)
            plotVel(app.MATname);
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.ReadyLamp.Color = 'green';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadFilesPanel = uipanel(app.UIFigure);
            app.LoadFilesPanel.Title = 'Load Files';
            app.Load = uibutton(app.LoadFilesPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load .ulg File';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.Exit = uibutton(app.OptionsPanel, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
            app.StatsPanel = uipanel(app.UIFigure);
            app.StatsPanel.Title = 'Stats';
            app.Max_u = uilabel(app.StatsPanel);
            app.Max_u.Text = '';
            app.Max_v = uilabel(app.StatsPanel);
            app.Max_v.Text = '';
            app.Max_alt = uilabel(app.StatsPanel);
            app.Max_alt.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'px4_logo.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.Alt = uibutton(app.PositionTab, 'push');
            app.Alt.ButtonPushedFcn = createCallbackFcn(app, @AltButtonPushed, true);
            app.Alt.Text = 'Plot Altitude from Baro';
            app.XYZ = uibutton(app.PositionTab, 'push');
            app.XYZ.ButtonPushedFcn = createCallbackFcn(app, @XYZButtonPushed, true);
            app.XYZ.Text = 'Plot X-Y-Z from GPS';
            app.VelocityTab = uitab(app.TabGroup);
            app.VelocityTab.Title = 'Velocity';
            app.Vel = uibutton(app.VelocityTab, 'push');
            app.Vel.ButtonPushedFcn = createCallbackFcn(app, @VelButtonPushed, true);
            app.Vel.Text = 'Plot Velocities from GPS ';
            app.AccelerationTab = uitab(app.TabGroup);
            app.AccelerationTab.Title = 'Acceleration';
            app.Accel = uibutton(app.AccelerationTab, 'push');
            app.Accel.ButtonPushedFcn = createCallbackFcn(app, @AccelButtonPushed, true);
            app.Accel.Text = 'Plot Acceleration Data ';
            app.LowPassFilterCutOffFrequencyHzEditFieldLabel = uilabel(app.AccelerationTab);
            app.LowPassFilterCutOffFrequencyHzEditFieldLabel.Text = 'Low-Pass Filter Cut-Off Frequency [Hz] =';
            app.LPFFreq = uieditfield(app.AccelerationTab, 'text');
            app.SetButton = uibutton(app.AccelerationTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.ReadyLampLabel = uilabel(app.AccelerationTab);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.AccelerationTab);
            app.ElseTab = uitab(app.TabGroup);
            app.ElseTab.Title = 'Else';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ulgGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end